classdef overlapDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        File           matlab.ui.container.Menu
        FileOpen       matlab.ui.container.Menu
        SaveFile       matlab.ui.container.Menu
        Thresh         matlab.ui.control.NumericEditField
        Label          matlab.ui.control.Label
        StartDetect    matlab.ui.control.Button
        OriginalBound  matlab.ui.control.UIAxes
        MatchBound     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        OriginalRGB % 原始RGB影像
        MatchRGB % 用于匹配的RGB影像
        BW % 掩模
        BWBoundary % 掩模边界
        OriginalOverlay % 待匹配图像的Overlay
        MatchOverlayBoundBox % 匹配模板的Overlay

    end

    methods (Access = private)

        function [BW,BWBoundary,OriginalOverlay,MatchOverlayBoundBox] = SURFMatch(app)
            %% 转灰度图
            if size(app.OriginalRGB,3)==3
                Original= im2double(rgb2gray(app.OriginalRGB));
            else
                Original=im2double(app.OriginalRGB);
            end

            if (size(app.MatchRGB,3))==3
                Match=im2double(rgb2gray(app.MatchRGB));
            else
                Match=im2double(app.MatchRGB);
            end
            %% 开始进行SURF匹配和变换
            %% 检测SURF特征点
            ptsOriginal  = detectSURFFeatures(Original);
            ptsMatch = detectSURFFeatures(Match);
            %% 提取特征点
            [featuresOriginal,validPtsOriginal] = extractFeatures(Original,ptsOriginal);
            [featuresMatch,validPtsMatch] = extractFeatures(Match,ptsMatch);
            %% 特征匹配
            index_pairs = matchFeatures(featuresOriginal,featuresMatch);
            matchedPtsOriginal  = validPtsOriginal(index_pairs(:,1));
            matchedPtsMatch = validPtsMatch(index_pairs(:,2));
            %% 估计变换矩阵
            [tform,~] = estimateGeometricTransform2D(matchedPtsMatch,matchedPtsOriginal,'similarity');
            %             inlierPtsMatch = matchedPtsMatch(inlierIdx,:);
            %             inlierPtsOriginal  = matchedPtsOriginal(inlierIdx,:);
            %% 匹配结果
            % 输出结果属性
            outputView = imref2d(size(Original));
            % 覆盖区域的掩模
            Mask = logical(imwarp(ones(size(Match)),tform,'OutputView',outputView));
            % 匹配RGB覆盖
            MatchRGBWarp=imwarp(app.MatchRGB,tform,'OutputView',outputView);
            % 匹配Warp覆盖
            MatchWarp=imwarp(Match,tform,'OutputView',outputView);
            %% 进行相减得到区域差异值
            Diff=ones(size(Mask));
            Diff(Mask)=abs(Original(Mask)-MatchWarp(Mask));
            %% 进行区域统计
            % 首先统计边界
            Boundary=bwboundaries(Diff<app.Thresh.Value,'noholes');
            % 取出最大数量的边界
            Num=cellfun(@length,Boundary);
            [~,maxInd]=max(Num);
            PixelList=Boundary{maxInd};
            % 产生该边界的掩模
            BW=poly2mask(PixelList(:,2),PixelList(:,1),size(Mask,1),size(Mask,2));
            % 产生该边界
            BWBoundary=boundarymask(BW);
            % 产生边界的外界矩形
            BWrp=regionprops(BW,'BoundingBox','Area');
            [~,AreaInd]=max(cat(1,BWrp.Area));
            BWBoundingBox=round(BWrp(AreaInd).BoundingBox);
            %% 叠加
            OriginalOverlay=imoverlay(app.OriginalRGB,BWBoundary);
            MatchOverlay=imoverlay(MatchRGBWarp,BWBoundary);
            MatchOverlayBoundBox=MatchOverlay(BWBoundingBox(2):BWBoundingBox(2)+BWBoundingBox(4),BWBoundingBox(1):BWBoundingBox(1)+BWBoundingBox(3),:);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartDetect.Enable='off';
            app.SaveFile.Enable='off';
        end

        % Menu selected function: FileOpen
        function FileOpenMenuSelected(app, event)
            [FileName1,Path1]=uigetfile("*.jpg;*.png",'请选择原始文件');
            [FileName2,Path2]=uigetfile("*.jpg;*.png",'请选择模板文件');
            if isequal(FileName1,0) || isequal(FileName2,0)
                return
            end
            % 读取文件
            app.OriginalRGB=imread([Path1,FileName1]);
            app.MatchRGB=imread([Path2,FileName2]);
            imshow(app.OriginalRGB,'Parent',app.OriginalBound);
            imshow(app.MatchRGB,'Parent',app.MatchBound);
            msgbox('文件打开成功')
            app.StartDetect.Enable='on';
        end

        % Button pushed function: StartDetect
        function StartDetectButtonPushed(app, event)
            try
                [app.BW,app.BWBoundary,app.OriginalOverlay,app.MatchOverlayBoundBox] = SURFMatch(app);
            catch
                errordlg('匹配报错，可能存在原因阈值过低导致无区域匹配，或图像特征过少')
                return;
            end
            imshow(app.OriginalOverlay,'Parent',app.OriginalBound);
            imshow(app.MatchOverlayBoundBox,'Parent',app.MatchBound);
            msgbox('匹配完成')
            app.SaveFile.Enable='on';
        end

        % Menu selected function: SaveFile
        function SaveFileMenuSelected(app, event)
            dirPath=uigetdir(".","选择用于输出的文件夹");
            if isequal(dirPath,0)
                return
            end
            BWName=[dirPath,'\模板.png'];
            BWBoundName=[dirPath,'\模板边界.png'];
            OriginalOverlayName=[dirPath,'\匹配图片重复区域.png'];
            MatchOverlayName=[dirPath,'\模板图片重复区域.png'];
            imwrite(app.BW,BWName);
            imwrite(app.BWBoundary,BWBoundName)
            imwrite(app.OriginalOverlay,OriginalOverlayName);
            imwrite(app.MatchOverlayBoundBox,MatchOverlayName);
            msgbox('输出完成')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = '文件';
            app.FileOpen = uimenu(app.File);
            app.FileOpen.MenuSelectedFcn = createCallbackFcn(app, @FileOpenMenuSelected, true);
            app.FileOpen.Text = '打开文件';
            app.SaveFile = uimenu(app.File);
            app.SaveFile.MenuSelectedFcn = createCallbackFcn(app, @SaveFileMenuSelected, true);
            app.SaveFile.Text = '保存文件';
            app.MatchBound = uiaxes(app.UIFigure);
            title(app.MatchBound, '模板图')
            app.MatchBound.XTick = [];
            app.MatchBound.YTick = [];
            app.OriginalBound = uiaxes(app.UIFigure);
            title(app.OriginalBound, '匹配图')
            app.OriginalBound.XTick = [];
            app.OriginalBound.YTick = [];
            app.StartDetect = uibutton(app.UIFigure, 'push');
            app.StartDetect.ButtonPushedFcn = createCallbackFcn(app, @StartDetectButtonPushed, true);
            app.StartDetect.Text = '开始检测';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '提取阈值';
            app.Thresh = uieditfield(app.UIFigure, 'numeric');
            app.Thresh.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = overlapDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end